classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        NewtonsXEditField            matlab.ui.control.EditField
        NewtonsXEditFieldLabel       matlab.ui.control.Label
        FirstXEditField              matlab.ui.control.NumericEditField
        FirstXEditFieldLabel         matlab.ui.control.Label
        ComputeButton                matlab.ui.control.Button
        instructions                 matlab.ui.control.TextArea
        MethodSwitch                 matlab.ui.control.Switch
        MethodSwitchLabel            matlab.ui.control.Label
        MinorMaxSwitch               matlab.ui.control.Switch
        MinorMaxSwitchLabel          matlab.ui.control.Label
        DeltaEditField               matlab.ui.control.NumericEditField
        DeltaEditFieldLabel          matlab.ui.control.Label
        SecondXEditField             matlab.ui.control.NumericEditField
        IntervalsLabel               matlab.ui.control.Label
        PlotFunctionButton           matlab.ui.control.Button
        FunctionEditField            matlab.ui.control.EditField
        FunctionEditFieldLabel       matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        EnterButton                  matlab.ui.control.Button
        IterationsLabel              matlab.ui.control.Label
        iterations                   matlab.ui.control.TextArea
        YLabel                       matlab.ui.control.Label
        functionResult               matlab.ui.control.TextArea
        ChangeTicksonAxisLabel       matlab.ui.control.Label
        y_2EditField_2               matlab.ui.control.EditField
        y_2EditField_2Label          matlab.ui.control.Label
        y_1EditField_2               matlab.ui.control.EditField
        y_1EditField_2Label          matlab.ui.control.Label
        x_2EditField_2               matlab.ui.control.EditField
        x_2EditField_2Label          matlab.ui.control.Label
        x_1EditField_2               matlab.ui.control.EditField
        x_1EditField_2Label          matlab.ui.control.Label
        point                        matlab.ui.control.TextArea
        PointLabel                   matlab.ui.control.Label
        y_2EditField                 matlab.ui.control.EditField
        y_2EditFieldLabel            matlab.ui.control.Label
        y_1EditField                 matlab.ui.control.EditField
        y_1Label                     matlab.ui.control.Label
        RefineGraphtoIntervalsLabel  matlab.ui.control.Label
        x_2EditField                 matlab.ui.control.EditField
        x_2EditFieldLabel            matlab.ui.control.Label
        x_1EditField                 matlab.ui.control.EditField
        x_1EditFieldLabel            matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu                         matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: FunctionEditField, PlotFunctionButton, UIAxes
        function PlotFunctionButtonPushed(app, event)
               input = app.FunctionEditField.Value;
               fx = str2func(['@(x)' input])
               label = strcat('y= ', input);
               fplot(app.UIAxes, fx);
               title(app.UIAxes, label);
               guidata(app.UIAxes, fx);
        end

        % Callback function
        function FunctionEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: EnterButton
        function x_1_Interval(app, event)
            x1 = app.x_1EditField.Value;
            x2 = app.x_2EditField.Value;
            y1 = app.y_1EditField.Value;
            y2 = app.y_2EditField.Value;
            if(x1 ~= "" && x2~= "")
                xlim(app.UIAxes, [x1 x2]);
            end
            if(y1 ~= "" && y2 ~= "")
                ylim(app.UIAxes, [y1 y2]);
            end
        end

        % Button pushed function: ComputeButton
        function callFunctions(app, event)
           if(app.MethodSwitch.Value = "Golden")
               x1 = str2double(app.FirstXEditField.value);
               x2 = str2double(app.SecondXEditField.value);
               delta = str2double(app.DeltaEditField.value);
               choice = 10;
               fx = guidata(app.UIAxes);
               if(app.MinorMaxSwitch.Value == "Min");
                   choice = 1;
               elseif(app.MinorMaxSwitch.Value == "Max");
                   choice = 0;
               end 
               [x y i] = goldenSection_W(x1, x2, fx, choice, delta);
           end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {746, 746};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {399, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FunctionEditFieldLabel = uilabel(app.LeftPanel);
            app.FunctionEditFieldLabel.Text = 'Function';
            app.FunctionEditField = uieditfield(app.LeftPanel, 'text');
            app.FunctionEditField.ValueChangedFcn = createCallbackFcn(app, @PlotFunctionButtonPushed, true);
            app.PlotFunctionButton = uibutton(app.LeftPanel, 'push');
            app.PlotFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFunctionButtonPushed, true);
            app.PlotFunctionButton.Text = 'Plot Function';
            app.IntervalsLabel = uilabel(app.LeftPanel);
            app.IntervalsLabel.Visible = 'off';
            app.IntervalsLabel.Text = 'Second X';
            app.SecondXEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SecondXEditField.Visible = 'off';
            app.DeltaEditFieldLabel = uilabel(app.LeftPanel);
            app.DeltaEditFieldLabel.Visible = 'off';
            app.DeltaEditFieldLabel.Text = 'Delta';
            app.DeltaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DeltaEditField.Visible = 'off';
            app.MinorMaxSwitchLabel = uilabel(app.LeftPanel);
            app.MinorMaxSwitchLabel.Visible = 'off';
            app.MinorMaxSwitchLabel.Text = 'Min or Max ';
            app.MinorMaxSwitch = uiswitch(app.LeftPanel, 'slider');
            app.MinorMaxSwitch.Items = {'Min', 'Max'};
            app.MinorMaxSwitch.Visible = 'off';
            app.MinorMaxSwitch.Value = 'Min';
            app.MethodSwitchLabel = uilabel(app.LeftPanel);
            app.MethodSwitchLabel.Text = 'Method';
            app.MethodSwitch = uiswitch(app.LeftPanel, 'slider');
            app.MethodSwitch.Items = {'Golden', 'Newton'};
            app.MethodSwitch.Value = 'Golden';
            app.instructions = uitextarea(app.LeftPanel);
            app.instructions.Visible = 'off';
            app.ComputeButton = uibutton(app.LeftPanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @callFunctions, true);
            app.ComputeButton.Visible = 'off';
            app.ComputeButton.Text = 'Compute';
            app.FirstXEditFieldLabel = uilabel(app.LeftPanel);
            app.FirstXEditFieldLabel.Visible = 'off';
            app.FirstXEditFieldLabel.Text = 'First X';
            app.FirstXEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FirstXEditField.Visible = 'off';
            app.NewtonsXEditFieldLabel = uilabel(app.LeftPanel);
            app.NewtonsXEditFieldLabel.Visible = 'off';
            app.NewtonsXEditFieldLabel.Text = 'Newtons X';
            app.NewtonsXEditField = uieditfield(app.LeftPanel, 'text');
            app.NewtonsXEditField.Visible = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @PlotFunctionButtonPushed, true);
            app.x_1EditFieldLabel = uilabel(app.RightPanel);
            app.x_1EditFieldLabel.Interpreter = 'latex';
            app.x_1EditFieldLabel.Text = '$x_{1}';
            app.x_1EditField = uieditfield(app.RightPanel, 'text');
            app.x_2EditFieldLabel = uilabel(app.RightPanel);
            app.x_2EditFieldLabel.Interpreter = 'latex';
            app.x_2EditFieldLabel.Text = '$x_{2}';
            app.x_2EditField = uieditfield(app.RightPanel, 'text');
            app.RefineGraphtoIntervalsLabel = uilabel(app.RightPanel);
            app.RefineGraphtoIntervalsLabel.Text = 'Refine Graph to Intervals:';
            app.y_1Label = uilabel(app.RightPanel);
            app.y_1Label.Interpreter = 'latex';
            app.y_1Label.Text = '$y_{1}';
            app.y_1EditField = uieditfield(app.RightPanel, 'text');
            app.y_2EditFieldLabel = uilabel(app.RightPanel);
            app.y_2EditFieldLabel.Interpreter = 'latex';
            app.y_2EditFieldLabel.Text = '$y_{2}';
            app.y_2EditField = uieditfield(app.RightPanel, 'text');
            app.PointLabel = uilabel(app.RightPanel);
            app.PointLabel.Visible = 'off';
            app.PointLabel.Text = 'Point';
            app.point = uitextarea(app.RightPanel);
            app.point.Editable = 'off';
            app.point.Visible = 'off';
            app.x_1EditField_2Label = uilabel(app.RightPanel);
            app.x_1EditField_2Label.Interpreter = 'latex';
            app.x_1EditField_2Label.Text = '$x_{1}';
            app.x_1EditField_2 = uieditfield(app.RightPanel, 'text');
            app.x_2EditField_2Label = uilabel(app.RightPanel);
            app.x_2EditField_2Label.Interpreter = 'latex';
            app.x_2EditField_2Label.Text = '$x_{2}';
            app.x_2EditField_2 = uieditfield(app.RightPanel, 'text');
            app.y_1EditField_2Label = uilabel(app.RightPanel);
            app.y_1EditField_2Label.Interpreter = 'latex';
            app.y_1EditField_2Label.Text = '$y_{1}';
            app.y_1EditField_2 = uieditfield(app.RightPanel, 'text');
            app.y_2EditField_2Label = uilabel(app.RightPanel);
            app.y_2EditField_2Label.Interpreter = 'latex';
            app.y_2EditField_2Label.Text = '$y_{2}';
            app.y_2EditField_2 = uieditfield(app.RightPanel, 'text');
            app.ChangeTicksonAxisLabel = uilabel(app.RightPanel);
            app.ChangeTicksonAxisLabel.Text = 'Change Ticks on Axis';
            app.functionResult = uitextarea(app.RightPanel);
            app.functionResult.Editable = 'off';
            app.functionResult.Visible = 'off';
            app.YLabel = uilabel(app.RightPanel);
            app.YLabel.Visible = 'off';
            app.YLabel.Text = 'Y';
            app.iterations = uitextarea(app.RightPanel);
            app.iterations.Editable = 'off';
            app.iterations.Visible = 'off';
            app.IterationsLabel = uilabel(app.RightPanel);
            app.IterationsLabel.Visible = 'off';
            app.IterationsLabel.Text = 'Iterations';
            app.EnterButton = uibutton(app.RightPanel, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @x_1_Interval, true);
            app.EnterButton.Text = 'Enter';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end